classdef Options < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptionsUIFigure              matlab.ui.Figure
        StartingframeEditFieldLabel  matlab.ui.control.Label
        StartingframeEditField       matlab.ui.control.NumericEditField
        FinalframeEditFieldLabel     matlab.ui.control.Label
        FinalframeEditField          matlab.ui.control.NumericEditField
        StepEditFieldLabel           matlab.ui.control.Label
        StepEditField                matlab.ui.control.NumericEditField
        SaveButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, stFrame, fiFrame, step)
            % Store main app in property for CloseRequestFcn to use
            movegui(app.OptionsUIFigure,'center');
            app.CallingApp = mainapp;
            
            % Update UI with input values
            app.StartingframeEditField.Value = stFrame;
            app.FinalframeEditField.Value = fiFrame;
            app.StepEditField.Value = step;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Call main app's public function
            updateOptions(app.CallingApp, app.StartingframeEditField.Value, app.FinalframeEditField.Value, app.StepEditField.Value);
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: OptionsUIFigure
        function OptionsUIFigureCloseRequest(app, event)
            % Enable the Opions button in main app
            app.CallingApp.OptionsButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptionsUIFigure = uifigure('Visible', 'off');
            app.OptionsUIFigure.Name = 'Options';
            app.OptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @OptionsUIFigureCloseRequest, true);
            app.StartingframeEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.StartingframeEditFieldLabel.Text = 'Starting frame';
            app.StartingframeEditField = uieditfield(app.OptionsUIFigure, 'numeric');
            app.StartingframeEditField.Value = 1;
            app.FinalframeEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.FinalframeEditFieldLabel.Text = 'Final frame';
            app.FinalframeEditField = uieditfield(app.OptionsUIFigure, 'numeric');
            app.FinalframeEditField.Value = 2;
            app.StepEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.OptionsUIFigure, 'numeric');
            app.StepEditField.Value = 1;
            app.SaveButton = uibutton(app.OptionsUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.OptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Options(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptionsUIFigure)
        end
    end
end